classdef S20App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        UIAxes               matlab.ui.control.UIAxes
        UIAxes2              matlab.ui.control.UIAxes
        RiderEditFieldLabel  matlab.ui.control.Label
        RiderEditField       matlab.ui.control.NumericEditField
        RouteEditFieldLabel  matlab.ui.control.Label
        RouteEditField       matlab.ui.control.NumericEditField
        PushToRunButton      matlab.ui.control.Button
        UIAxes3              matlab.ui.control.UIAxes
        ElevationLabel       matlab.ui.control.Label
    end


    methods (Access = private)
    
        function[lat, long, elevation, distance, HR, speed, results, Weight] = RiderSelect(app)
            input = (app.RiderEditField.Value);
            data = 0;
            Weight = 0;
            if input == 1
                data = xlsread('tt_rider01.xlsx');
                Weight = 163.2;
            elseif input == 2
                data = xlsread('tt_rider02.xlsx');
                Weight = 164.2;
            elseif input == 3
                data = xlsread('tt_rider03.xlsx');
                Weight = 170;
            elseif input == 4
                data = xlsread('tt_rider04.xlsx');
                Weight = 157.0;
            elseif input == 5
                data = xlsread('tt_rider05.xlsx');
                Weight = 162.4;
            elseif input == 6
                data = xlsread('tt_rider06.xlsx');
                Weight = 168.4;
            elseif input == 7
                data = xlsread('tt_rider07.xlsx');
                Weight = 170.8;
            elseif input == 8
                data = xlsread('tt_rider08.xlsx');
                Weight = 172.4;
            elseif input == 9
                data = xlsread('tt_rider09.xlsx');
                Weight = 162.4;
            elseif input == 10
                data = xlsread('tt_rider10.xlsx');
                Weight = 181.6;
            end
            lat = data(:,1);
            long = data(:,2);
            elevation = data(:,3);
            distance = data(:,4);
            HR = data(:,5);
            speed = data(:,6);
            results = data;
            
        end
        
        function[lat, long, elevation, distance, results] = RouteData(app)
            input = str2num(app.RouteEditField.Value);
            data = 0;
            if input == 1
                data = load('Cuhrb_DP.dat');
            elseif input == 2
                data = load('Masto_DP.dat');
            elseif input == 3
                data = load('MTU_mid_DP.dat');
            elseif input == 4
                data = load('Nara_DP.dat');
            elseif input == 5
                data = load('swedetown_DP.dat');
            end
            lat = data(:,1);
            long = data(:,2);
            elevation = data(:,3);
            distance = data(:,4);
            results = data;
        end
        
        function dt = findTime(app, distance, speed)
            dt = 0;
            for t = 2:length(distance)
                if((distance(t)-distance(t-1)) == 0)
                   dt(t) = dt(t-1);
                else
                dt(t) = (distance(t)-distance(t-1))/(speed(t));
                end                
            end            
        end
        
        function slope = slopeCalc(app, elevation, distance)
            for t = 2:length(distance)
                slope(t) = atan((elevation(t)-elevation(t-1))/(distance(t)-distance(t-1)));
            end
        end
        
        function totalElevation = elevationGain(app, elevation)
            totalElevation = 0;
            for t = 2:length(elevation)
                if elevation(t) > elevation(t-1) 
                    totalElevation = totalElevation + (elevation(t) - elevation(t-1));
                end
            end
        end
        
    end


    methods (Access = private)

        % Button pushed function: PushToRunButton
        function PushToRunButtonPushed(app, event)
            [lat, long, elevation, distance, HR, speed, results, Weight] = RiderSelect(app);
            dt = findTime(app, distance, speed);
            slope = slopeCalc(app, elevation, distance);
            totalElevation = elevationGain(app, elevation);
            
            plot3(app.UIAxes, lat, long, elevation);
            plot(app.UIAxes2, distance, elevation);
            totalTime = dt(1);
            for t = 2:length(dt)
                totalTime(t) = dt(t) + totalTime(t-1);
            end
            plot(app.UIAxes3, totalTime, slope);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Latitude / Longitude')
            xlabel(app.UIAxes, 'Latitude')
            ylabel(app.UIAxes, 'Longitude')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Distance vs Elevation')
            xlabel(app.UIAxes2, 'Distance')
            ylabel(app.UIAxes2, 'Elevation')
            app.RiderEditFieldLabel = uilabel(app.UIFigure);
            app.RiderEditFieldLabel.Text = 'Rider';
            app.RiderEditField = uieditfield(app.UIFigure, 'numeric');
            app.RiderEditField.Value = 1;
            app.RouteEditFieldLabel = uilabel(app.UIFigure);
            app.RouteEditFieldLabel.Text = 'Route';
            app.RouteEditField = uieditfield(app.UIFigure, 'numeric');
            app.RouteEditField.Value = 1;
            app.PushToRunButton = uibutton(app.UIFigure, 'push');
            app.PushToRunButton.ButtonPushedFcn = createCallbackFcn(app, @PushToRunButtonPushed, true);
            app.PushToRunButton.Text = 'Push To Run ';
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'Time')
            ylabel(app.UIAxes3, 'Slope')
            app.ElevationLabel = uilabel(app.UIFigure);
            app.ElevationLabel.Text = 'Elevation';
        end
    end

    methods (Access = public)

        % Construct app
        function app = S20App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end